classdef CSVDataExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CorrelationButton           matlab.ui.control.Button
        SummaryTextArea             matlab.ui.control.TextArea
        SummaryTextAreaLabel        matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        PlotButton                  matlab.ui.control.Button
        PlotTypeDropDown            matlab.ui.control.DropDown
        PlotTypeDropDownLabel       matlab.ui.control.Label
        YDropDown                   matlab.ui.control.DropDown
        YDropDownLabel              matlab.ui.control.Label
        XDropDown                   matlab.ui.control.DropDown
        XDropDownLabel              matlab.ui.control.Label
        ColumnSelectorListBox       matlab.ui.control.ListBox
        ColumnSelectorListBoxLabel  matlab.ui.control.Label
        UndoButton                  matlab.ui.control.Button
        CreateColumnButton          matlab.ui.control.Button
        FormulaEditField            matlab.ui.control.EditField
        FormulaEditFieldLabel       matlab.ui.control.Label
        NormalizeButton             matlab.ui.control.Button
        FillMissingMedianButton     matlab.ui.control.Button
        FillMissingMeanButton       matlab.ui.control.Button
        LoadCSVButton               matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        T table           %Current working table
        T_original table  %Original table
        UndoStack cell    %Stack for undo
    end
    
    methods (Access = private)
        
        function startupFcn(app)
            app.UndoStack = {};
        end
        
        function updateTableDisplay(app)
            app.UITable.Data = app.T;

            vars = app.T.Properties.VariableNames;
            types = cellfun(@class, ... 
                table2cell(app.T(1,:)), ...
                'UniformOutput', false);
            missing = varfun(@(x) sum(ismissing(x)), app.T);

            summaryText = "";

            for i = 1:length(vars)
                missingCount = missing{1,i};

                summaryText = summaryText + ...
                    vars{i} + " | " + types{i} + ...
                    " | Missing: " + missingCount + newline;
            end
            app.SummaryTextArea.Value = summaryText;
        end
        
        function updateDropdowns(app)
            if isempty(app.T)
                return;
            end

            vars = app.T.Properties.VariableNames;
            numVars = app.T(:, vartype('numeric')).Properties.VariableNames;
            
            app.XDropDown.Items = vars;
            app.YDropDown.Items = numVars;

            if ~isempty(vars)
                app.XDropDown.Value = vars{1};
            end

            if ~isempty(numVars)
                app.YDropDown.Value = numVars{1};
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadCSVButton
        function LoadCSVButtonPushed(app, event)
            [file, path] = uigetfile('*.csv');
            if isequal(file,0)
                return;
            end

            app.T = readtable(fullfile(path,file));
            app.T_original = app.T;
            
            app.UndoStack = {};
            app.UndoStack{end+1} = app.T;

            updateTableDisplay(app);
            updateDropdowns(app);
        end

        % Button pushed function: FillMissingMeanButton
        function FillMissingMeanButtonPushed(app, event)
            app.UndoStack{end+1} = app.T;

            numVars = varfun(@isnumeric, app.T, 'OutputFormat', 'uniform');

            for i = find(numVars)
                x = app.T{:,i};

                if all(isnan(x))
                    continue;
                end

                mu = mean(x, 'omitnan');
                x(isnan(x)) = mu;

                app.T{:,i} = x;
            end
            
            updateTableDisplay(app);
        end

        % Button pushed function: FillMissingMedianButton
        function FillMissingMedianButtonPushed(app, event)
             app.UndoStack{end+1} = app.T;

            numVars = varfun(@isnumeric, app.T, 'OutputFormat', 'uniform');

            for i = find(numVars)
                x = app.T{:,i};

                if all(isnan(x))
                    continue;
                end

                mu = median(x, 'omitnan');
                x(isnan(x)) = mu;

                app.T{:,i} = x;
            end

            updateTableDisplay(app);
        end

        % Button pushed function: NormalizeButton
        function NormalizeButtonPushed(app, event)
            app.UndoStack{end+1} = app.T;

            numVars = varfun(@isnumeric, app.T, 'OutputFormat', 'unifomr');

            for i = find(numVars)
                x = app.T{:,i};

                mu = mean(x, 'omitnan');
                s = std(x, 'omitnan');

                if s == 0 || isnan(s)
                    continue;
                end

                z = (x - mu) / s;

                newName = app.T.Properties.VariableNames{i} + "_z";
                app.T.(newName) = z;
            end

            updateTableDisplay(app);
            updateDropdowns(app);
        end

        % Button pushed function: CreateColumnButton
        function CreateColumnButtonPushed(app, event)
            expr = app.FormulaEditField.Value;

            if isempty(regexp(expr, '^[a-zA-Z0-9_+\-.*\/\^\(\) ]+$', 'once'))
                uialert(app.UIFigure, 'Invalid formula', 'Error');
                return;
            end

            app.UndoStack{end+1} = app.T;

            S = struct();
            vars = app.T.Properties.VariableNames;

            for i = 1:length(vars)
                S.(vars{i}) = app.T.(vars{i});
            end

            fields = fieldnames(S);
            for i = 1:numel(fields)
                eval([fields{i} ' = S.(fields{i});']);
            end

            try
                y = eval(expr);
            catch
                uialert(app.UIFigure, 'Error evaluating formula', 'Error');
                return;
            end

            if length(y) ~= height(app.T)
                uialert(app.UIFigure, 'Size mismatch', 'Error');
                return;
            end

            app.T.NewColumn = y;

            updateTableDisplay(app);
            updateDropdowns(app);
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if length(app.UndoStack) < 2
                return;
            end

            app.UndoStack(end) = [];
            app.T = app.UndoStack{end};

            updateTableDisplay(app);
            updateDropdowns(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes);

            type = app.PlotTypeDropDown.Value;
            xVar = app.XDropDown.Value;

            x = app.T.(xVar);

            switch type
                case 'Line'
                    plot(app.UIAxes, x);
                    xlabel(app.UIAxes, xVar)

                case 'Scatter'
                    yVar = app.YDropDown.Value;
                    y = app.T.(yVar);

                    scatter(app.UIAxes, x, y);
                    xlabel(app.UIAxes, xVar)
                    ylabel(app.UIAxes, yVar)

                case 'Histogram'
                    histogram(app.UIAxes, x);
                    xlabel(app.UIAxes, xVar)
            end
        end

        % Button pushed function: CorrelationButton
        function CorrelationButtonPushed(app, event)
            numData = app.T(:, vartype('numeric'));
            X = table2array(numData);

            R = corrcoef(X, 'Rows', 'pairwise');

            imagesc(app.UIAxes, R);
            colorbar(app.UIAxes);
            axis(app.UIAxes, 'image');

            vars = numData.Properties.VariableNames;
            app.UIAxes.XTick = 1:length(vars);
            app.UIAxes.YTick = 1:length(vars);
            
            app.UIAxes.XTickLabel = vars;
            app.UIAxes.YTickLabel = vars;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadCSVButton = uibutton(app.UIFigure, 'push');
            app.LoadCSVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCSVButtonPushed, true);
            app.LoadCSVButton.Text = 'Load CSV';
            app.FillMissingMeanButton = uibutton(app.UIFigure, 'push');
            app.FillMissingMeanButton.ButtonPushedFcn = createCallbackFcn(app, @FillMissingMeanButtonPushed, true);
            app.FillMissingMeanButton.Text = 'Fill Missing Mean';
            app.FillMissingMedianButton = uibutton(app.UIFigure, 'push');
            app.FillMissingMedianButton.ButtonPushedFcn = createCallbackFcn(app, @FillMissingMedianButtonPushed, true);
            app.FillMissingMedianButton.Text = 'Fill Missing Median';
            app.NormalizeButton = uibutton(app.UIFigure, 'push');
            app.NormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeButtonPushed, true);
            app.NormalizeButton.Text = 'Normalize';
            app.FormulaEditFieldLabel = uilabel(app.UIFigure);
            app.FormulaEditFieldLabel.Text = 'Formula';
            app.FormulaEditField = uieditfield(app.UIFigure, 'text');
            app.CreateColumnButton = uibutton(app.UIFigure, 'push');
            app.CreateColumnButton.ButtonPushedFcn = createCallbackFcn(app, @CreateColumnButtonPushed, true);
            app.CreateColumnButton.Text = 'Create Column';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.ColumnSelectorListBoxLabel = uilabel(app.UIFigure);
            app.ColumnSelectorListBoxLabel.Text = 'Column Selector';
            app.ColumnSelectorListBox = uilistbox(app.UIFigure);
            app.ColumnSelectorListBox.Items = {'Column 1', 'Column 2', 'Column 3', 'Column 4'};
            app.ColumnSelectorListBox.Value = 'Column 1';
            app.XDropDownLabel = uilabel(app.UIFigure);
            app.XDropDownLabel.Text = 'X';
            app.XDropDown = uidropdown(app.UIFigure);
            app.YDropDownLabel = uilabel(app.UIFigure);
            app.YDropDownLabel.Text = 'Y';
            app.YDropDown = uidropdown(app.UIFigure);
            app.PlotTypeDropDownLabel = uilabel(app.UIFigure);
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.PlotTypeDropDown = uidropdown(app.UIFigure);
            app.PlotTypeDropDown.Items = {'Line', 'Scatter', 'Histogram'};
            app.PlotTypeDropDown.Value = 'Line';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SummaryTextAreaLabel = uilabel(app.UIFigure);
            app.SummaryTextAreaLabel.Text = 'Summary';
            app.SummaryTextArea = uitextarea(app.UIFigure);
            app.CorrelationButton = uibutton(app.UIFigure, 'push');
            app.CorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @CorrelationButtonPushed, true);
            app.CorrelationButton.Text = 'Correlation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSVDataExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end