classdef csvDataWrangler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FillMissingMedianButton     matlab.ui.control.Button
        UndoButton                  matlab.ui.control.Button
        CreateColumnButton          matlab.ui.control.Button
        NormalizeButton             matlab.ui.control.Button
        FillMissingMeanButton       matlab.ui.control.Button
        YVariableDropDown           matlab.ui.control.DropDown
        YVariableDropDownLabel      matlab.ui.control.Label
        XVariableDropDown           matlab.ui.control.DropDown
        XVariableDropDownLabel      matlab.ui.control.Label
        ColumnSelectorListBox       matlab.ui.control.ListBox
        ColumnSelectorListBoxLabel  matlab.ui.control.Label
        PlotTypeDropDown            matlab.ui.control.DropDown
        PlotTypeDropDownLabel       matlab.ui.control.Label
        LoadCSVButton               matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        CSVDataWranglerLabel        matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        T table            %Current table
        T_original table   %Original table
        UndoStack cell     %Stack for undo
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadCSVButton
        function LoadCSVButtonPushed(app, event)
            [file, path] = uigetfile('*.csv');
            if isequal(file,0)
                return;
            end

            app.T = readtable(fullfile(path, file));
            app.T_original = app.T;

            %Push to undo stack
            app.UndoStack{end+1} = app.T;

            updateTableDisplay(app);
        end

        % Button pushed function: FillMissingMeanButton
        function FillMissingMeanButtonPushed(app, event)
            app.UndoStack{end+1} = app.T;

            numVars = varfun(@isnumeric, app.T, 'OutputFormat', 'uniform');

            for i = find(numVars)
                x = app.T(:, i);

                if all(isnan(x))
                    continue;
                end

                mu = mean(x, 'omitnan');
                x(isnan(x)) = mu;

                app.T{:,i} = x;
            end
            updateTableDisplay(app);
        end

        % Button pushed function: FillMissingMedianButton
        function FillMissingMedianButtonPushed(app, event)
            app.UndoStack{end+1} = app.T;

            numVars = varfun(@isnumeric, app.T, 'OutputFormat', 'uniform');

            for i = find(numVars)
                x = app.T{:, i};

                if all(isnan(x))
                    continue;
                end

                med = median(x, 'omitnan');
                x(isnan(x)) = med;

                app.T{:, i} = x;
            end

            updateTableDisplay(app);
        end

        % Button pushed function: NormalizeButton
        function NormalizeButtonPushed(app, event)
            app.UndoStack{end+1} = app.T;

            numVars = varfun(@isnumeric, app.T, 'OutputFormat', 'uniform');

            for i = find(numVars)
                x = app.T{:, i};

                mu = mean(x, 'omitnan');
                s = std(x, 'omitnan');

                if s == 0 || isnan(s)
                    continue;
                end

                z = (x - mu) / s;

                newName = app.T.Properties.VariableNames{i} + "_z";
                app.T.(newName) = z;
            end
            updateTableDisplay(app);
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if length(app.UndoStack) < 2
                return;
            end

            app.UndoStack(end) = [];
            app.T = app.UndoStack{end};

            updateTableDisplay(app);
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)

            
        end

        % Button pushed function: CreateColumnButton
        function CreateColumnButtonPushed(app, event)

        end

        % Drop down opening function: PlotTypeDropDown
        function PlotTypeDropDownOpening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CSVDataWranglerLabel = uilabel(app.UIFigure);
            app.CSVDataWranglerLabel.Text = 'CSV Data Wrangler';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.LoadCSVButton = uibutton(app.UIFigure, 'push');
            app.LoadCSVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCSVButtonPushed, true);
            app.LoadCSVButton.Text = 'Load CSV';
            app.PlotTypeDropDownLabel = uilabel(app.UIFigure);
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.PlotTypeDropDown = uidropdown(app.UIFigure);
            app.PlotTypeDropDown.Items = {'Line', 'Scatter', 'Histogram'};
            app.PlotTypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PlotTypeDropDownOpening, true);
            app.PlotTypeDropDown.Value = 'Line';
            app.ColumnSelectorListBoxLabel = uilabel(app.UIFigure);
            app.ColumnSelectorListBoxLabel.Text = 'Column Selector';
            app.ColumnSelectorListBox = uilistbox(app.UIFigure);
            app.ColumnSelectorListBox.Items = {'Column 1', 'Column 2', 'Column 3', 'Column 4'};
            app.ColumnSelectorListBox.Value = 'Column 1';
            app.XVariableDropDownLabel = uilabel(app.UIFigure);
            app.XVariableDropDownLabel.Text = 'X Variable';
            app.XVariableDropDown = uidropdown(app.UIFigure);
            app.XVariableDropDown.Items = {'Column 1', 'Column 2', 'Column 3', 'Column 4'};
            app.XVariableDropDown.Value = 'Column 1';
            app.YVariableDropDownLabel = uilabel(app.UIFigure);
            app.YVariableDropDownLabel.Text = 'Y Variable';
            app.YVariableDropDown = uidropdown(app.UIFigure);
            app.YVariableDropDown.Items = {'Column 1', 'Column 2', 'Column 3', 'Column 4'};
            app.YVariableDropDown.Value = 'Column 1';
            app.FillMissingMeanButton = uibutton(app.UIFigure, 'push');
            app.FillMissingMeanButton.ButtonPushedFcn = createCallbackFcn(app, @FillMissingMeanButtonPushed, true);
            app.FillMissingMeanButton.Text = 'Fill Missing (Mean)';
            app.NormalizeButton = uibutton(app.UIFigure, 'push');
            app.NormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeButtonPushed, true);
            app.NormalizeButton.Text = 'Normalize';
            app.CreateColumnButton = uibutton(app.UIFigure, 'push');
            app.CreateColumnButton.ButtonPushedFcn = createCallbackFcn(app, @CreateColumnButtonPushed, true);
            app.CreateColumnButton.Text = 'Create Column';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.FillMissingMedianButton = uibutton(app.UIFigure, 'push');
            app.FillMissingMedianButton.ButtonPushedFcn = createCallbackFcn(app, @FillMissingMedianButtonPushed, true);
            app.FillMissingMedianButton.Text = 'Fill Missing (Median)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = csvDataWrangler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end